classdef project_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LoadClassesButton      matlab.ui.control.Button
        DisplayImagesButton    matlab.ui.control.Button
        TestImageLabel         matlab.ui.control.Label
        PredictedImageLabel    matlab.ui.control.Label
        LRCforFaceRecognitionClassificationLabel  matlab.ui.control.Label
        ClassesLoadedLabel     matlab.ui.control.Label
        NumClassesLoadedLabel  matlab.ui.control.Label
        ImagesLoadedLabel      matlab.ui.control.Label
        NumImagesLoadedLabel   matlab.ui.control.Label
        InputTestImageDisplay  matlab.ui.control.UIAxes
        PredictedImageDisplay  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        classFiles % A matrix where the second dimension has 11 elements. Each entry has the class name first, then the 10 file paths for that class.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadClassesButton
        function LoadClasses(app, event)
            % Get the path to the folder containing the face classes.
            face_path = uigetdir();
            fprintf(1, "User opened path: %s\n", face_path);
            face_folders = dir(face_path); % Get each unique class in the face folder.
            
            % Construct an array of class names, excluding the '.' and '..' entries.
            % We assume each class starts with the letter 's'.
            classes = strings([length(face_folders) - 2,1]);
            classes_i = 1;
            for face_folders_i = 1:length(face_folders)
               if face_folders(face_folders_i).name(1) == 's'
                  classes(classes_i) = face_folders(face_folders_i).name;
                  classes_i = classes_i + 1;
               end
            end
            
            % Compile a list of class paths.
            classpaths = strings([length(classes), 1]);
            for i = 1:length(classpaths)
                % fullfile() builds the full file path from its arguments, OS specific.
                classpaths(i) = fullfile(face_path, classes(i));
            end
            
            % Compile a list of image paths for each class.
            % Each class is guaranteed to have exactly 10 images.
            % The first entry is the class name, followed by the paths for each of the 10 images.
            class_filepaths = strings([length(classpaths), 11]);
            % For each class path...
            for i = 1:length(classpaths)
                class_filepaths(i,1) = classes(i); % Assign the current class name.
                files = dir(classpaths(i)); % Get all the files, including '.' and '..'.
                j = 2;
                % Add each file if it has a ".pgm" extension.
                for files_i = 1:length(files)
                    [~,~,ext] = fileparts(files(files_i).name);
                    if ext == ".pgm"
                        class_filepaths(i,j) = fullfile(classpaths(i), files(files_i).name);
                        j = j + 1;
                    end
                end
            end
            
            set(app.NumClassesLoadedLabel, 'Text', sprintf("%d",size(class_filepaths,1)));
            set(app.NumImagesLoadedLabel, 'Text', sprintf("%d",size(class_filepaths,1) * (size(class_filepaths,2) - 1)));
            % Add our class files to the global scope.
            app.classFiles = class_filepaths;
        end

        % Button pushed function: DisplayImagesButton
        function DisplayImages(app, event)
            for i = 1:size(app.classFiles,1)
                for j = 2:size(app.classFiles,2)
                    current_img = imread(app.classFiles(i,j));
                    imshow(current_img, 'Parent', app.PredictedImageDisplay);
                    drawnow
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LoadClassesButton = uibutton(app.GridLayout, 'push');
            app.LoadClassesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadClasses, true);
            app.LoadClassesButton.Text = 'Load Classes';
            app.DisplayImagesButton = uibutton(app.GridLayout, 'push');
            app.DisplayImagesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayImages, true);
            app.DisplayImagesButton.Text = 'Display Images';
            app.TestImageLabel = uilabel(app.GridLayout);
            app.TestImageLabel.Text = 'Test Image';
            app.PredictedImageLabel = uilabel(app.GridLayout);
            app.PredictedImageLabel.Text = 'Predicted Image';
            app.LRCforFaceRecognitionClassificationLabel = uilabel(app.GridLayout);
            app.LRCforFaceRecognitionClassificationLabel.Text = 'LRC for Face Recognition & Classification';
            app.ClassesLoadedLabel = uilabel(app.GridLayout);
            app.ClassesLoadedLabel.Text = 'Classes Loaded:';
            app.NumClassesLoadedLabel = uilabel(app.GridLayout);
            app.NumClassesLoadedLabel.Text = '0';
            app.ImagesLoadedLabel = uilabel(app.GridLayout);
            app.ImagesLoadedLabel.Text = 'Images Loaded:';
            app.NumImagesLoadedLabel = uilabel(app.GridLayout);
            app.NumImagesLoadedLabel.Text = '0';
            app.InputTestImageDisplay = uiaxes(app.GridLayout);
            app.InputTestImageDisplay.XTick = [];
            app.InputTestImageDisplay.XTickLabel = '';
            app.InputTestImageDisplay.YTick = [];
            app.PredictedImageDisplay = uiaxes(app.GridLayout);
            app.PredictedImageDisplay.XTick = [];
            app.PredictedImageDisplay.XTickLabel = '';
            app.PredictedImageDisplay.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end